classdef gui_control < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ControlModeStateTextArea       matlab.ui.control.TextArea
        ControlModeStateTextAreaLabel  matlab.ui.control.Label
        MotorSpeedTextArea             matlab.ui.control.TextArea
        MotorSpeedTextAreaLabel        matlab.ui.control.Label
        MotorStateTextArea             matlab.ui.control.TextArea
        MotorStateTextAreaLabel        matlab.ui.control.Label
        MotorSpeedKnob                 matlab.ui.control.Knob
        MotorSpeedKnobLabel            matlab.ui.control.Label
        ShutdownButton                 matlab.ui.control.Button
        SystemOnButton                 matlab.ui.control.Button
        ControlModeButton              matlab.ui.control.Button
        ReverseMotorButton             matlab.ui.control.Button
        ForwardMotorButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        arduinoObj                    % Serial port object
        power_button logical           % System ON/OFF
        reverse_button logical         % Reverse direction flag
        control_mode_button logical   % Control mode flag
        ref_duty_cycle double;
        Vout double;
        motor_speed_constant = 373.33;
        pause_value = 0.1; % in sec
        port = "COM7";
        baud_rate = 9600;
        updateTimer timer;
    end
    
    methods (Access = private)
        
        function acknoledgement(app)
            pause(app.pause_value);
            % while app.arduinoObj.NumBytesAvailable > 0
            %     try
            %         response = readline(app.arduinoObj);
            %         disp("Arduino replied: " + response);
            %     catch ME
            %         disp("Read error: " + ME.message);
            %         break;
            %     end
            % end
            if(app.arduinoObj.NumBytesAvailable > 0)
                % readline(app.arduinoObj);
                response = readline(app.arduinoObj);
                disp("Arduino replied: " + response);
                response = readline(app.arduinoObj);
                disp("Arduino replied: " + response);
            end
            % while app.arduinoObj.NumBytesAvailable > 0
            %     try
            %         disp(readline(app.arduinoObj));
            %     catch ME
            %         disp("Read error: " + ME.message);
            %         break;
            %     end
            % end
        end
        
        function updateGUIFromArduino(app)
            try
                while app.arduinoObj.NumBytesAvailable > 0
                    line = readline(app.arduinoObj);
                    tokens = split(strtrim(line), ",");
                    if numel(tokens) == 5
                        app.power_button   = str2double(tokens{1});
                        app.reverse_button = str2double(tokens{2});
                        app.control_mode_button   = str2double(tokens{3});
                        app.ref_duty_cycle = str2double(tokens{4});
                        app.Vout =  str2double(tokens{5});
    
                        % --- Update GUI fields ---
                        % Motor state
                        if app.power_button == 0
                            app.MotorStateTextArea.Value = "OFF";
                        elseif app.reverse_button == 1
                            app.MotorStateTextArea.Value = "Reverse Rotating";
                        else
                            app.MotorStateTextArea.Value = "Forward Rotating";
                        end
    
                        % Control mode
                        if app.control_mode_button == 1
                            app.ControlModeStateTextArea.Value = "Closed Loop Control";
                        else
                            app.ControlModeStateTextArea.Value = "Open Loop Control";
                        end
    
                        % Motor speed
                        % app.motor_speed_constant = 100; % example: 100 RPM per Volt
                        speed = app.motor_speed_constant *app. Vout*(app.Vout >= 4);
                        app.MotorSpeedTextArea.Value = sprintf('Speed: %.1f RPM\nDuty Cycle: %.3f', speed,app.ref_duty_cycle);
    
                        % Optional: display duty cycle too
                        % app.DutyCycleTextArea.Value = sprintf('%.3f', app.ref_duty_cycle);
                    end
                end
            catch
                % If any error (like disconnect), safely ignore
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try
                app.arduinoObj = serialport(app.port, app.baud_rate); % select the port
                pause(0.5);
                % if(app.arduinoObj.NumBytesAvailable > 0)
                %     response = readline(app.arduinoObj);
                %     disp("Starting Application");
                %     disp("Arduino replied: " + response);
                % end
                app.acknoledgement();
                disp("Starting Application");

                % % Create and start the timer
                % app.updateTimer = timer( ...
                %     "ExecutionMode", "fixedSpacing", ...
                %     "Period", 0.1, ...        % 100 ms
                %     "TimerFcn", @(~,~) updateGUIFromArduino(app), ...
                %     "ErrorFcn", @(~,~) disp("Timer error.") ...
                % );
                % start(app.updateTimer);
                % disp("Connected and live updating started.");
            catch
                uialert(app.UIFigure, 'Unable to connect to Arduino. Check COM port.', 'Connection Error');
                return;
            end

            app.power_button = false;
            app.reverse_button = false;
            app.control_mode_button = false;

            app.MotorStateTextArea.Value = "System Off - Forward Rotating";
            app.ControlModeStateTextArea.Value = "Open Loop Control";
            app.MotorSpeedTextArea.Value = "";
        end

        % Button pushed function: SystemOnButton
        function SystemOnButtonPushed(app, event)
            app.power_button = true;
            write(app.arduinoObj, "POWER_ON", "string");
            disp("Sent to Arduino: POWER_ON");

            % pause(app.pause_value);
            % if(app.arduinoObj.NumBytesAvailable > 0)
            %     response = readline(app.arduinoObj);
            %     disp("Arduino replied: " + response);
            % end
            app.acknoledgement();
            try
                % Create and start the timer
                app.updateTimer = timer( ...
                    "ExecutionMode", "fixedSpacing", ...
                    "Period", 0.2, ...        % 100 ms
                    "TimerFcn", @(~,~) updateGUIFromArduino(app), ...
                    "ErrorFcn", @(~,~) disp("Timer error.") ...
                );
                start(app.updateTimer);
                disp("Connected and live updating started.");
            catch ME
                uialert(app.UIFigure, ME.message, 'Connection Error')
            end

            app.MotorStateTextArea.Value = "System On";
        end

        % Button pushed function: ForwardMotorButton
        function ForwardMotorButtonPushed(app, event)
            app.reverse_button = false;
            write(app.arduinoObj, "FORWARD", "string");
            disp("Sent to Arduino: FORWARD");
            
            % pause(app.pause_value);
            % if(app.arduinoObj.NumBytesAvailable > 0)
            %     response = readline(app.arduinoObj);
            %     disp("Arduino replied: " + response);
            % end
            % while app.arduinoObj.NumBytesAvailable > 0
            %     try
            %         response = readline(app.arduinoObj);
            %         disp("Arduino replied: " + response);
            %     catch ME
            %         disp("Read error: " + ME.message);
            %         break;
            %     end
            % end
            app.acknoledgement();

            app.MotorStateTextArea.Value = "Forward Rotating";
        end

        % Button pushed function: ReverseMotorButton
        function ReverseMotorButtonPushed(app, event)
            app.reverse_button = true;
            write(app.arduinoObj, "REVERSE", "string");
            disp("Sent to Arduino: REVERSE");
            
            % pause(app.pause_value);
            % if(app.arduinoObj.NumBytesAvailable > 0)
            %     response = readline(app.arduinoObj);
            %     disp("Arduino replied: " + response);
            % end
            app.acknoledgement();

            app.MotorStateTextArea.Value = "Reverse Rotating";
        end

        % Button pushed function: ControlModeButton
        function ControlModeButtonPushed(app, event)
            app.control_mode_button = ~app.control_mode_button;
            if app.control_mode_button
                write(app.arduinoObj, "CLOSED_LOOP", "string");
                disp("Sent to Arduino: CLOSED_LOOP");
                app.ControlModeStateTextArea.Value = "Closed Loop Control";
            else
                write(app.arduinoObj, "OPEN_LOOP", "string");
                disp("Sent to Arduino: OPEN_LOOP");
                app.ControlModeStateTextArea.Value = "Open Loop Control";
            end

            % pause(app.pause_value);
            %  if(app.arduinoObj.NumBytesAvailable > 0)
            %     response = readline(app.arduinoObj);
            %     disp("Arduino replied: " + response);
            % end
            app.acknoledgement();
        end

        % Button pushed function: ShutdownButton
        function ShutdownButtonPushed(app, event)
            write(app.arduinoObj, "SHUTDOWN", "string");
            disp("Sent to Arduino: SHUTDOWN");
            
            % pause(app.pause_value);
            % if(app.arduinoObj.NumBytesAvailable > 0)
            %     response = readline(app.arduinoObj);
            %     disp("Arduino replied: " + response);
            % end
            app.acknoledgement();

            app.MotorStateTextArea.Value = "Shutdown!";
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                if isvalid(app.arduinoObj)
                    write(app.arduinoObj, "SHUTDOWN", "string");
                    clear app.arduinoObj
                end
                if isvalid(app.updateTimer)
                    stop(app.updateTimer);
                    delete(app.updateTimer);
                end
            catch
            end
            delete(app)
        end

        % Value changed function: MotorSpeedKnob
        function MotorSpeedKnobValueChanged(app, event)
            speed_value = app.MotorSpeedKnob.Value;   % Read current knob value (0–100)
            write(app.arduinoObj, sprintf("SPEED:%d", round(speed_value)), "string");
            disp("Sent to Arduino: " + sprintf("SPEED:%d", round(speed_value)));
        
            % Optional: read acknowledgment if Arduino sends one
            % pause(app.pause_value);
            % if (app.arduinoObj.NumBytesAvailable > 0)
            %     response = readline(app.arduinoObj);
            %     disp("Arduino replied: " + response);
            % end
            app.acknoledgement();
        
            % Update display on GUI
            % app.MotorSpeedTextArea.Value = sprintf("Speed: %d %%", round(speed_value));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ForwardMotorButton = uibutton(app.UIFigure, 'push');
            app.ForwardMotorButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardMotorButtonPushed, true);
            app.ForwardMotorButton.Tooltip = {'Start the motor in the forward direction'};
            app.ForwardMotorButton.Text = 'Forward Motor';
            app.ReverseMotorButton = uibutton(app.UIFigure, 'push');
            app.ReverseMotorButton.ButtonPushedFcn = createCallbackFcn(app, @ReverseMotorButtonPushed, true);
            app.ReverseMotorButton.Tooltip = {'Rotate the motor in the reverse direction'};
            app.ReverseMotorButton.Text = 'Reverse Motor';
            app.ControlModeButton = uibutton(app.UIFigure, 'push');
            app.ControlModeButton.ButtonPushedFcn = createCallbackFcn(app, @ControlModeButtonPushed, true);
            app.ControlModeButton.Tooltip = {'Switch Between Open Loop Control and Close Loop Control'};
            app.ControlModeButton.Text = 'Control Mode Button';
            app.SystemOnButton = uibutton(app.UIFigure, 'push');
            app.SystemOnButton.ButtonPushedFcn = createCallbackFcn(app, @SystemOnButtonPushed, true);
            app.SystemOnButton.Tooltip = {'Turn the System On'};
            app.SystemOnButton.Text = 'System On';
            app.ShutdownButton = uibutton(app.UIFigure, 'push');
            app.ShutdownButton.ButtonPushedFcn = createCallbackFcn(app, @ShutdownButtonPushed, true);
            app.ShutdownButton.Tooltip = {'Shutdown The System'};
            app.ShutdownButton.Text = 'Shutdown';
            app.MotorSpeedKnobLabel = uilabel(app.UIFigure);
            app.MotorSpeedKnobLabel.Text = 'Motor Speed';
            app.MotorSpeedKnob = uiknob(app.UIFigure, 'continuous');
            app.MotorSpeedKnob.ValueChangedFcn = createCallbackFcn(app, @MotorSpeedKnobValueChanged, true);
            app.MotorSpeedKnob.Tooltip = {'Control the Speed of the Motor'};
            app.MotorStateTextAreaLabel = uilabel(app.UIFigure);
            app.MotorStateTextAreaLabel.Text = 'Motor State';
            app.MotorStateTextArea = uitextarea(app.UIFigure);
            app.MotorSpeedTextAreaLabel = uilabel(app.UIFigure);
            app.MotorSpeedTextAreaLabel.Text = 'Motor Speed';
            app.MotorSpeedTextArea = uitextarea(app.UIFigure);
            app.ControlModeStateTextAreaLabel = uilabel(app.UIFigure);
            app.ControlModeStateTextAreaLabel.Text = 'Control Mode State';
            app.ControlModeStateTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui_control

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end